classdef Practica1_A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Generadordesenal               matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SealesListBoxLabel             matlab.ui.control.Label
        SealesListBox                  matlab.ui.control.ListBox
        ColorListBoxLabel              matlab.ui.control.Label
        ColorListBox                   matlab.ui.control.ListBox
        GraficarButton                 matlab.ui.control.Button
        MultiplesVariablesLampLabel    matlab.ui.control.Label
        MultiplesVariablesLamp         matlab.ui.control.Lamp
        VariableLampLabel              matlab.ui.control.Label
        VariableLamp                   matlab.ui.control.Lamp
        EscalarLabel                   matlab.ui.control.Label
        EscalarLamp                    matlab.ui.control.Lamp
        VectorialLampLabel             matlab.ui.control.Label
        VectorialLamp                  matlab.ui.control.Lamp
        DiscretasLampLabel             matlab.ui.control.Label
        DiscretasLamp                  matlab.ui.control.Lamp
        ContinuasLampLabel             matlab.ui.control.Label
        ContinuasLamp                  matlab.ui.control.Lamp
        NmerodeVariablesLabel          matlab.ui.control.Label
        DimensionalidadLabel           matlab.ui.control.Label
        VariablesIndependientesLabel   matlab.ui.control.Label
        ValoresdelaSealLabel           matlab.ui.control.Label
        DiscretosLampLabel             matlab.ui.control.Label
        DiscretosLamp                  matlab.ui.control.Lamp
        ContinuosLampLabel             matlab.ui.control.Label
        ContinuosLamp                  matlab.ui.control.Lamp
        NaturalezaEstadisticaLabel     matlab.ui.control.Label
        DeterministasLampLabel         matlab.ui.control.Label
        DeterministasLamp              matlab.ui.control.Lamp
        AleatoriasLampLabel            matlab.ui.control.Label
        AleatoriasLamp                 matlab.ui.control.Lamp
        CaracteristicasdelaSealSwitchLabel  matlab.ui.control.Label
        CaracteristicasdelaSealSwitch  matlab.ui.control.Switch
        CheckBox_2                     matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        DescripcinTextAreaLabel        matlab.ui.control.Label
        DescripcinTextArea             matlab.ui.control.TextArea
        LimpiarButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SealesListBox
        function SealesListBoxValueChanged(app, event)
            signal = app.SealesListBox.Value;
            switch signal
                case 'Ruido Rosa'
                    app.DescripcinTextArea.Value = ['El patrón de ruido rosa se produce en un gran número' ...
                        'de sistemas naturales, incluyendo los ritmos de los latidos del corazón, la luminosidad ' ...
                        'de los cuasares, o el flujo del tráfico.'];
                case 'Ruido Blanco'
                    app.DescripcinTextArea.Value = ['el ruido blanco sería el que hace un televisor sin sintonizar, ' ...
                        'al igual que lo serían el sonido de la lluvia, el de un ventilador o el de las olas del mar.'];
                case 'Senoidal'
                    app.DescripcinTextArea.Value = ['Muestra comportamientos periodicos, por esto mismo' ...
                        'podemos encontrarla en situaciones como las fases de la luna o el día y la noche.'];
                case 'Ruido Marron'
                    app.DescripcinTextArea.Value = [ 'movimiento circulatorio de unas particulas microscopicas en ' ...
                        'suspensiones acuosas, lo que se denomino el "movimiento browniano"'];
                case 'Escalón'
                    app.DescripcinTextArea.Value = ['Con esta señal se observa algo que cada cierto tiempo aumenta o disminuye' ...
                        ' por lo que se muestra una grafica con forma de escalera, podemos observar esta señal en el costo de un' ...
                        'estacionamiento que cada cierto tiempo cuesta más con forme el coche pase más tiempo estacionado.'];
                case 'Impulso Triangular'
                    app.DescripcinTextArea.Value = ['Como su nombre indica, esta señal se compone de un pulso cuya aplitud es infinita,' ...
                        'en la vida real no podemos recrear esa infinidad pero si usar aproximaciones con pequeños pulsos, un ejemplo' ...
                        'de un pulso. sería un latido de nuestro corazón.'];
                case 'Rampa'
                    app.DescripcinTextArea.Value = ['Esta señal refleja un aumento, pero a diferencia de otras señales, esta requiere de' ...
                        'tiempo para realizar el cambio, como por ejemplo un cambio de temperatura el cual se va dando con el paso de las' ...
                        'horas o incluso días.'];
                case 'Rectangular'
                    app.DescripcinTextArea.Value = ['Funciona como la señal de impulso solo que el pulso dura más tiempo en las señales' ...
                        'rectangulares'];
                case 'Triangular'
                    app.DescripcinTextArea.Value = ['Un ejemplo de esta señal la podemos observar en el ciclo de marea de la costa a que cuando la' ...
                        'marea sube, el nivel del agua sube hasta un máximo para luego disminuir devuelta al mínimo y se repeti este proceso.'];
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            signal = app.SealesListBox.Value;
            color = app.ColorListBox.Value;
            
            switch color
                case 'Rojo'
                    c = 'r';
                case 'Verde'
                    c = 'g';
                case 'Negro'
                    c = 'k';
                case 'Magenta'
                    c = 'm';
            end
            
            switch signal
                case 'Ruido Blanco'
                    % Definir el vector de tiempo
                    t = linspace(0, 10, 1000);
                    
                    % Definir la señal de ruido blanco
                    f = randn(1, 1000);
                    
                    % Graficar la señal
                    plot(t, f,c);
                    title('Señal de Ruido Blanco');
                    xlabel('Tiempo (s)');
                    ylabel('Amplitud');
                    
                case 'Senoidal'
                    f = 0.5;
                    phi = pi/2;
                    Fs = 10000;
                    A=1;
                    T = 3*(1/f);
                    t = 0:1/Fs:T-1/Fs;
                    x =A *sin(2*pi*f*t+phi);
                    plot(app.UIAxes,t, x,c),grid on,ylim([-1.1 1.1]);
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),
                    title(app.SealesListBox.Value)
                    
                case 'Ruido Rosa'
                    % Configuración de la señal de ruido rosa
                    Fs = 44100; % Frecuencia de muestreo en Hz
                    T = 1; % Duración de la señal en segundos
                    noise = dsp.ColoredNoise('Color', 'pink', 'SamplesPerFrame', Fs*T);
                    
                    % Generación de la señal
                    x = noise();
                    
                    % Mostrar la señal en el gráfico
                    plot(app.UIAxes, x,c);
                    
                case 'Ruido Marron'
                    
                    % Configuración de la señal de ruido marrón
                    Fs = 44100; % Frecuencia de muestreo en Hz
                    T = 1; % Duración de la señal en segundos
                    noise = dsp.ColoredNoise('Color', 'brown', 'SamplesPerFrame', Fs*T);
                    
                    % Generación de la señal
                    x = noise();
                    
                    % Mostrar la señal en el gráfico
                    plot(app.UIAxes, x,c);
                case 'Escalón'
                    Fs = 10000;
                    A=1;
                    t = 0:1/Fs:10; %Tiempo de Simulación
                    % Generación de las señales
                    x1 = A*heaviside(t); % Señal escalón
                    plot(app.UIAxes,t, x1, c, 'LineWidth', 2)
                    grid on % Agregar la rejilla
                    xlabel('Tiempo (s)') % Etiqueta del eje x
                    ylabel('Amplitud') % Etiqueta del eje y
                    title(app.SealesListBox.Value)
                case 'Impulso Triangular'
                    n = 0:5; %Tiempo de Simulación
                    A=1;
                    y = A.*[zeros(1,3),ones(1,1),zeros(1,2)];
                    plot(app.UIAxes,n,y,c),grid on,xlim([0 5]),ylim([0 1.1])
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),
                    title(app.SealesListBox.Value)
                case 'Rampa'
                    Fs = 10000;
                    t1 = 0:1/Fs:5;
                    t2 = 5:1/Fs:10;
                    A=1;
                    r = t1;
                    t = [t1,t2]; %Tiempo de Simulación
                    y = A.*[zeros(1,length(t1)),r]; %Generación de Señal
                    plot(app.UIAxes,t,y,c),grid on,xlim([0 10]),ylim([0 5]) %Grafica
                    xlabel('Tiempo (s)') % Etiqueta del eje x
                    ylabel('Amplitud') % Etiqueta del eje y
                    title(app.SealesListBox.Value)
                case 'Rectangular'
                    Fs = 10000;
                    t= -1:1/Fs:1;
                    A=1;
                    y = A * (abs(t) <= 0.5/2);
                    plot(app.UIAxes,t,y,c),grid on,xlim([-1 1]),ylim([0.1 1.1]) %Gráfica %Gráfica
                    xlabel('Tiempo (s)') % Etiqueta del eje x
                    ylabel('Amplitud') % Etiqueta del eje y
                    title(app.SealesListBox.Value)
                case 'Triangular'
                    t = -2:1/100:2;
                    A=1;
                    y= A*triangularPulse(t);
                    plot(app.UIAxes,t,y,c),grid on,ylim([-0.1 1.1]) %Grafica
                    xlabel('Tiempo (s)') % Etiqueta del eje x
                    ylabel('Amplitud') % Etiqueta del eje y
                    title(app.SealesListBox.Value)
            end
        end

        % Callback function
        function LimpiarButtonPushed(app, event)
            
        end

        % Value changed function: CaracteristicasdelaSealSwitch
        function CaracteristicasdelaSealSwitchValueChanged(app, event)
            signal = app.SealesListBox.Value;
            switchState = app.CaracteristicasdelaSealSwitch.Value;
            
            
            switch signal
                case 'Ruido Blanco'
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                        app.DeterministasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.AleatoriasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VectorialLamp.Color = [1, 0, 0];
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        app.DiscretasLamp.Color = [1, 0, 0];
                    end
                    
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        app.DiscretosLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.MultiplesVariablesLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                    end
                    
                case'Ruido Rosa'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                        app.DeterministasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.AleatoriasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VectorialLamp.Color = [1, 0, 0];
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        app.DiscretasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        app.DiscretosLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                        app.MultiplesVariablesLamp.Color = [1, 0, 0];
                    end
                case 'Senoidal'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                        app.DeterministasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.AleatoriasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.VectorialLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.DiscretasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        
                    end
                    if switchState == "on"
                        app.DiscretosLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        
                    end
                    if switchState == "on"
                        app.VariableLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.MultiplesVariablesLamp.Color = [1, 0, 0];
                    end
                case 'Ruido Marron'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                        app.DeterministasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.AleatoriasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VectorialLamp.Color = [1, 0, 0];
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        app.DiscretasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        app.DiscretosLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                        app.MultiplesVariablesLamp.Color = [1, 0, 0];
                    end
                    
                case 'Escalón'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                     app.AleatoriasLamp.Color = [1, 0, 0];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.DeterministasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.VectorialLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.ContinuasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        
                        app.DiscretasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.DiscretosLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        
                    end
                    if switchState == "on"
                        app.MultiplesVariablesLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                    end
                case 'Impulso Triangular'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                        app.DeterministasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.AleatoriasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VectorialLamp.Color = [1, 0, 0];
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        app.DiscretasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        app.DiscretosLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.MultiplesVariablesLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                    end
                case 'Rampa'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                        app.AleatoriasLamp.Color = [1, 0, 0];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.DeterministasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.VectorialLamp.Color = [1, 0, 0];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.DiscretasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        
                    end
                    if switchState == "on"
                        app.DiscretosLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        
                    end
                    if switchState == "on"
                        app.MultiplesVariablesLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                    end
                case 'Rectangular'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                     app.AleatoriasLamp.Color = [1, 0, 0];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.DeterministasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VectorialLamp.Color = [1, 0, 0];
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        app.DiscretasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        app.DiscretosLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.MultiplesVariablesLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                    end
                    
                case 'Triangular'
                    
                    % el interruptor está encendido, enciende la lámpara
                    if switchState == "on"
                        app.DeterministasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.AleatoriasLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.VectorialLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        
                        app.EscalarLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.DiscretasLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuasLamp.Color = [1, 0, 0];
                        
                    end
                    if switchState == "on"
                        
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.ContinuosLamp.Color = [1, 0, 0];
                        app.DiscretosLamp.Color = [1, 0, 0];
                    end
                    if switchState == "on"
                        app.MultiplesVariablesLamp.Color = [1, 1, 1];
                        % Si el interruptor está apagado, apaga la lámpara
                    else
                        app.VariableLamp.Color = [1, 0, 0];
                    end
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            signal = app.SealesListBox.Value;
            checkbox= app.CheckBox_2.Value;


            switch signal
                case 'Ruido Rosa'
                    if checkbox
                        h = msgbox(['el ruido rosa univariable es una señal aleatoria en una sola dimensión ' ...
                            '(el tiempo). Sin embargo, también puede haber ruido rosa multivariable, que puede' ...
                            'tener varias dimensiones (por ejemplo, tiempo y frecuencia) y varias variables independientes. ' ...
                            'En el caso de una señal univariable en una sola dimensión, el ruido rosa es una señal escalar. ' ...
                            'Sin embargo, si la señal es multivariable y tiene varias dimensiones, como el tiempo y la frecuencia, ' ...
                            'entonces el ruido rosa se convierte en una señal vectorial.'], 'Justificacion', 'help', 'modal');
                        
                        % Esperar a que el usuario haga clic en el botón "OK"
                        uiwait(h);
                    end
                    
                case 'Ruido Blanco'
                    if checkbox
                        h = msgbox(['Una señal de ruido blanco es una señal aleatoria,una señal de' ...
                            'ruido blanco se puede describir por una sola variable, que es su amplitud. En ' ...
                            'el caso de una señal de ruido blanco discreto, las muestras tienen valores discretos' ...
                            'y se pueden representar como una secuencia de valores numéricos. En el ' ...
                            'caso de una señal de ruido blanco continuo, la señal se puede representar como una función continua ' ...
                            'de tiempo. En este caso, la señal se describe por una función que asigna un valor de amplitud a cada ' ...
                            'instante de tiempo.'], 'Justificacion', 'help', 'modal');
                        
                        % Esperar a que el usuario haga clic en el botón "OK"
                        uiwait(h);
                    end
                case 'Senoidal'
                    if checkbox
                        h = msgbox(['Una señal senoidal (por ejemplo la voz) es una señal de una sola variable en el tiempo, ' ...
                            'y esta misma es continua,la dimensionalidad es escalar porque es producida por una sola fuente' ...
                            '(no más de una dimensión) y finalmente, la naturaleza es aleatoria debido a que una voz no puede ' ...
                            'ser representada matemáticamente, no de una forma precisa o concreta'], 'Justificacion', 'help', 'modal');
                        
                        % Esperar a que el usuario haga clic en el botón "OK"
                        uiwait(h);
                    end
                case 'Ruido Marron'
                    if checkbox
                        h = msgbox(['La fluctuación de los precios de las acciones, el ruido marrón' ...
                            'puede ser considerado una señal escalar, ya que se trata de una sola magnitud (el precio) que varía ' ...
                            'aleatoriamente con el tiempo. Por otro lado, si se está analizando la señal de radio, el ruido marrón ' ...
                            'puede ser considerado una señal vectorial, ya que se compone de múltiples componentes de frecuencia y amplitud ' ...
                            'que varían aleatoriamente con el tiempo. el ruido marrón puede ser una señal unidimensional o de ' ...
                            'una variable, como una serie de tiempo de una sola magnitud, por ejemplo, el precio de una acción.' ...
                            'Sin embargo, en otros casos, el ruido marrón puede ser una señal multidimensional o de varias variables, ' ...
                            'como en el caso de una imagen en la que cada pixel tiene un valor que varía aleatoriamente con el tiempo.'], ...
                            'Justificacion', 'help', 'modal');
                        
                        % Esperar a que el usuario haga clic en el botón "OK"
                        uiwait(h);
                    end
                case 'Escalón'
                    
                    h = msgbox(['La señal que genera encender la luz de una habitación es de tipo discreta, con variables' ...
                        'independientes igualmente discretas si se toma en cuenta que oscila únicamente entre dos' ...
                        'valores o estados posibles, un caso diferente sería si se midiera la intensidad lumínica.' ...
                        'Finalmente, su naturaleza es determinista, puesto que no hay gran dificultad en generar una' ...
                        'función que representa el comportamiento de esta acción de forma factible'],'Justificacion', 'help', 'modal');
                    
                    % Esperar a que el usuario haga clic en el botón "OK"
                    uiwait(h);
                case 'Impulso Triangular'
                    
                    h = msgbox('', 'Justificacion', 'help', 'modal');
                    
                    % Esperar a que el usuario haga clic en el botón "OK"
                    uiwait(h);
                case 'Rampa'
                    
                    h = msgbox(['La carga de un capacitor igualmente es una señal dependiente del tiempo continuo, cuyos' ...
                        'valores serán de la misma naturaleza puesto que se tendrá un valor en cada instante de'...
                        'tiempo medido. Es escalar porque se tendrá un único valor en un tiempo t.'], 'Justificacion', 'help', 'modal');
                    
                    % Esperar a que el usuario haga clic en el botón "OK"
                    uiwait(h);
                case 'Rectangular'
                    
                    h = msgbox('', 'Justificacion', 'help', 'modal');
                    
                    % Esperar a que el usuario haga clic en el botón "OK"
                    uiwait(h);
                case 'Triangular'
                    
                    h = msgbox(['La señal de la medición de temperatura es una señal de una variable, dependiente del' ...
                        'tiempo que es continuo; cuyos valores pueden ser tanto discretos como continuos según el' ...
                        'método por el cual haya sido medida'], 'Justificacion', 'help', 'modal');
                    
                    % Esperar a que el usuario haga clic en el botón "OK"
                    uiwait(h);
            end


        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed2(app, event)
            cla(app.UIAxes); % Limpia los datos de la gráfica
            set(app.DeterministasLamp,'Color',[1 1 1]);
            set(app.AleatoriasLamp,'Color',[1 1 1]);
            set(app.VectorialLamp,'Color',[1 1 1]);
            set(app.EscalarLamp,'Color',[1 1 1]);
            set(app.ContinuasLamp,'Color',[1 1 1]);
            set(app.DiscretasLamp,'Color',[1 1 1]);
            set(app.ContinuosLamp,'Color',[1 1 1]);
            set(app.DiscretosLamp,'Color',[1 1 1]);
            set(app.VariableLamp,'Color',[1 1 1]);
            set(app.MultiplesVariablesLamp,'Color',[1 1 1]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Generadordesenal.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {528, 528};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {415, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Generadordesenal = uifigure('Visible', 'off');
            app.Generadordesenal.Name = 'Pulse Generator';
            app.Generadordesenal.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Generadordesenal);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SealesListBoxLabel = uilabel(app.LeftPanel);
            app.SealesListBoxLabel.Text = 'Señales:';
            app.SealesListBox = uilistbox(app.LeftPanel);
            app.SealesListBox.Items = {'Triangular', 'Ruido Rosa', 'Ruido Marron', 'Senoidal', 'Ruido Blanco', 'Escalón', 'Impulso Triangular', 'Rampa', 'Rectangular'};
            app.SealesListBox.ValueChangedFcn = createCallbackFcn(app, @SealesListBoxValueChanged, true);
            app.SealesListBox.Value = {};
            app.ColorListBoxLabel = uilabel(app.LeftPanel);
            app.ColorListBoxLabel.Text = 'Color:';
            app.ColorListBox = uilistbox(app.LeftPanel);
            app.ColorListBox.Items = {'Rojo', 'Verde', 'Negro', 'Magenta'};
            app.ColorListBox.Value = 'Rojo';
            app.GraficarButton = uibutton(app.LeftPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.MultiplesVariablesLampLabel = uilabel(app.LeftPanel);
            app.MultiplesVariablesLampLabel.Text = 'Multiples Variables';
            app.MultiplesVariablesLamp = uilamp(app.LeftPanel);
            app.VariableLampLabel = uilabel(app.LeftPanel);
            app.VariableLampLabel.Text = '1 Variable';
            app.VariableLamp = uilamp(app.LeftPanel);
            app.EscalarLabel = uilabel(app.LeftPanel);
            app.EscalarLabel.Text = 'Escalar  ';
            app.EscalarLamp = uilamp(app.LeftPanel);
            app.VectorialLampLabel = uilabel(app.LeftPanel);
            app.VectorialLampLabel.Text = 'Vectorial';
            app.VectorialLamp = uilamp(app.LeftPanel);
            app.DiscretasLampLabel = uilabel(app.LeftPanel);
            app.DiscretasLampLabel.Text = 'Discretas ';
            app.DiscretasLamp = uilamp(app.LeftPanel);
            app.ContinuasLampLabel = uilabel(app.LeftPanel);
            app.ContinuasLampLabel.Text = 'Continuas';
            app.ContinuasLamp = uilamp(app.LeftPanel);
            app.NmerodeVariablesLabel = uilabel(app.LeftPanel);
            app.NmerodeVariablesLabel.Text = 'Número de Variables ';
            app.DimensionalidadLabel = uilabel(app.LeftPanel);
            app.DimensionalidadLabel.Text = 'Dimensionalidad';
            app.VariablesIndependientesLabel = uilabel(app.LeftPanel);
            app.VariablesIndependientesLabel.Text = 'Variables Independientes ';
            app.ValoresdelaSealLabel = uilabel(app.LeftPanel);
            app.ValoresdelaSealLabel.Text = 'Valores de la Señal';
            app.DiscretosLampLabel = uilabel(app.LeftPanel);
            app.DiscretosLampLabel.Text = 'Discretos';
            app.DiscretosLamp = uilamp(app.LeftPanel);
            app.ContinuosLampLabel = uilabel(app.LeftPanel);
            app.ContinuosLampLabel.Text = 'Continuos';
            app.ContinuosLamp = uilamp(app.LeftPanel);
            app.NaturalezaEstadisticaLabel = uilabel(app.LeftPanel);
            app.NaturalezaEstadisticaLabel.Text = 'Naturaleza Estadistica';
            app.DeterministasLampLabel = uilabel(app.LeftPanel);
            app.DeterministasLampLabel.Text = 'Deterministas';
            app.DeterministasLamp = uilamp(app.LeftPanel);
            app.AleatoriasLampLabel = uilabel(app.LeftPanel);
            app.AleatoriasLampLabel.Text = 'Aleatorias';
            app.AleatoriasLamp = uilamp(app.LeftPanel);
            app.CaracteristicasdelaSealSwitchLabel = uilabel(app.LeftPanel);
            app.CaracteristicasdelaSealSwitchLabel.Text = 'Caracteristicas de la Señal';
            app.CaracteristicasdelaSealSwitch = uiswitch(app.LeftPanel, 'slider');
            app.CaracteristicasdelaSealSwitch.Items = {'', ''};
            app.CaracteristicasdelaSealSwitch.ValueChangedFcn = createCallbackFcn(app, @CaracteristicasdelaSealSwitchValueChanged, true);
            app.CaracteristicasdelaSealSwitch.Value = '';
            app.CheckBox_2 = uicheckbox(app.LeftPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'background-gef9b1c8b2_1280.jpg';
            app.DescripcinTextAreaLabel = uilabel(app.RightPanel);
            app.DescripcinTextAreaLabel.Text = 'Descripción:';
            app.DescripcinTextArea = uitextarea(app.RightPanel);
            app.DescripcinTextArea.Editable = 'off';
            app.DescripcinTextArea.Value = {'Con esta señal se puede reflejar cualquier cambio entre dos estados, como podría ser el cambio entre luz y obscuridad.'};
            app.LimpiarButton = uibutton(app.RightPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed2, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            % Show the figure after all components are created
            app.Generadordesenal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica1_A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Generadordesenal)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Generadordesenal)
        end
    end
end