classdef Practica2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Generadordesenal            matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        GeneradordesealeselementalesLabel  matlab.ui.control.Label
        FrecuenciaEditField_2Label  matlab.ui.control.Label
        FrecuenciaEditField         matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel      matlab.ui.control.Label
        AmplitudEditField           matlab.ui.control.NumericEditField
        TiempoEditFieldLabel        matlab.ui.control.Label
        TiempoEditField             matlab.ui.control.NumericEditField
        GenerarSealButton           matlab.ui.control.Button
        ElijaunasealDropDownLabel   matlab.ui.control.Label
        ElijaunasealDropDown        matlab.ui.control.DropDown
        OperacinDropDownLabel       matlab.ui.control.Label
        OperacinDropDown            matlab.ui.control.DropDown
        LimpiarSealButton           matlab.ui.control.Button
        EvaluarSealButton           matlab.ui.control.Button
        TipodeSeal1DropDown_2       matlab.ui.control.DropDown
        TipodeSeal1DropDownLabel_2  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        Image2                      matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarSealButton
        function GenerarSealButtonPushed(app, event)
            % Obtener los valores ingresados por el usuario
            Amplitud = app.AmplitudEditField.Value;
            Frecuencia = app.FrecuenciaEditField.Value;
            Tiempo = app.TiempoEditField.Value;
            Clasificacion=app.OperacinDropDown.Value;
            
            % Datos de la señal
            switch app.ElijaunasealDropDown.Value
                case 'Senoidal'
                    switch Clasificacion
                        case 'Por Tiempo'
                            % Cuando es continua
                            Fs=150;
                            t=-(Tiempo):1/Fs:Tiempo;
                            y = Amplitud*sin(2*pi*Frecuencia*t);
                            plot(app.UIAxes, t, y); % Graficar señal
                            title(app.UIAxes, 'Señal senoidal en tiempo continuo');
                            %Cuando es dicreta
                            n = -(Tiempo):Tiempo; % Vector de tiempo discreto
                            phi = pi/2; % Fase de la señal
                            y = Amplitud*sin(2*pi*Frecuencia*n - phi); % Señal senoidal
                            stem(app.UIAxes2, n, y); % Graficar señal
                            title(app.UIAxes2, 'Señal senoidal en tiempo discreto');
                        case 'Por Periocidad'
                            t = linspace(0, 1/Frecuencia, 1000); % Vector de tiempo
                            x = Amplitud * sin(2*pi*Frecuencia*t); % Generación de la señal
                            plot(app.UIAxes, t, x); % Graficar señal
                            title(app.UIAxes, 'Señal senoidal periódica');
                            
                            t1 = linspace(0, 3/Frecuencia, 1000); % Vector de tiempo
                            x1 = Amplitud * sin(2*pi*Frecuencia*t); % Generación de la señal
                            plot(app.UIAxes2, t1, x1); % Graficar señal
                            title(app.UIAxes2, 'Señal senoidal no periódica');
                        case 'Por Componente'
                            t = linspace(0, Tiempo, 1000); % Vector de tiempo
                            
                            % Componente par
                            x_par = Amplitud/2 * cos(2*pi*Frecuencia*t);
                            plot(app.UIAxes, t, x_par);
                            title(app.UIAxes, 'Componente par de señal senoidal');
                            
                            % Componente impar
                            x_impar = Amplitud/2 * sin(2*pi*2*Frecuencia*t);
                            plot(app.UIAxes2, t, x_impar);
                            title(app.UIAxes2, 'Componente impar de señal senoidal');
                            
                    end
                    
                case 'Cuadrada'
                    switch Clasificacion
                        case 'Por Tiempo'
                            Fs = 10000; %Frecuencia de Muestreo
                            duty=50;
                            t = 0:1/Fs:Tiempo-1/Fs; %Tiempo de Simulación
                            y = Amplitud*square(2*pi*Frecuencia*t,duty);
                            %Grafica de la señal
                            plot(app.UIAxes,t,y,'k'),grid on
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes,'Señal Cuadrada en tiempo continuo')
                            
                            duty = 50; %Ciclo útil
                            n = 0:Tiempo; %Tiempo de Simulación
                            y = Amplitud*square(2*pi*Frecuencia*n,duty); %Generación de Señal
                            stem(app.UIAxes2,n,y,'r'),grid on %Grafica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title(app.UIAxes2,'Señal Cuadrada en tiempo discreto')
                        case 'Por Periocidad'
                            % Generar señal cuadrada periódica
                            Fs = 100000; %Frecuencia de Muestreo
                            duty=50;
                            t = 0:1/Fs:Tiempo-1/Fs; %Tiempo de Simulación
                            y = Amplitud*square(2*pi*Frecuencia*t,duty);
                            y = y / max(abs(y));
                            % Graficar señal
                            plot(app.UIAxes, t, y);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal cuadrada periódica');
                       
                            % Generar señal cuadrada no periódica
                            Fs = 1000; %Frecuencia de Muestreo
                            t = 0:1/Fs:Tiempo-1/Fs; %Tiempo de Simulación
                            umbral = 0.1;
                            rand_seq = rand(size(t)); % Generar una secuencia aleatoria de valores entre 0 y 1
                            y = 2*(2*(rand_seq > umbral) - 1);
                             y = y / max(abs(y));
                            % Graficar la señal
                            plot(app.UIAxes2,t, y);
                            xlabel('Tiempo (s)');
                            ylabel('Amplitud');
                            title('Señal cuadrada no periódica');
                            
                        case 'Por Componente'
                            % Generar señal cuadrada par
                            Fs = 10000; %Frecuencia de Muestreo
                            t = 0:1/Fs:Tiempo-1/Fs; %Tiempo de Simulación
                            x = Amplitud*square(2*pi*Frecuencia*t,50);
                            x_par = (1/2)*(x+fliplr(x));
                            % Graficar señal par
                            plot(app.UIAxes, t, x_par);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Componente par de señal cuadrada');
                            % Generar señal cuadrada impar
                            Fs = 10000; %Frecuencia de Muestreo
                            t1 = 0:1/Fs:Tiempo-1/Fs; %Tiempo de Simulación
                            x = Amplitud*square(2*pi*Frecuencia*t1,50);
                            x_impar = (1/2)*(x-fliplr(x));
                            % Graficar señal impar
                            plot(app.UIAxes2, t, x_impar);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Componente impar de señal cuadrada');
                    end
                    
                case 'Triangular'
                    switch Clasificacion
                        case 'Por Tiempo'
                            t=-Tiempo:1/Frecuencia:Tiempo;
                            y= Amplitud*triangularPulse(t);
                            plot(app.UIAxes,t,y,'m*'),grid on %Grafica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Señal Triangular en tiempo continuo')
                            
                            % Señal triangular en tiempo discreto
                            n = -(Tiempo):0.1:Tiempo; %Tiempo de Simulación
                            y = Amplitud*triangularPulse(n); %Generación de Señal
                            stem(app.UIAxes2,n,y);
                            title(app.UIAxes2,'Señal triangular en tiempo discreto')
                        case 'Por Periocidad'
                            % Señal triangular periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            y= Amplitud*sawtooth(2*pi*Frecuencia*t,0.5);
                            
                            plot(app.UIAxes,t,y);
                            title(app.UIAxes,'Señal triangular periódica')
                            
                            % Señal triangular no periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            y = Amplitud*abs(mod(t,1/Frecuencia)-1/(2*Frecuencia));
                            plot(app.UIAxes2,t,y);
                            title(app.UIAxes2,'Señal triangular no periódica')
                        case 'Por Componente'
                            t=-Tiempo:1/Frecuencia:Tiempo;
                            y= Amplitud*triangularPulse(t);
                            y_impar = (y-fliplr(y));
                            plot(app.UIAxes2,t,y_impar);
                            title(app.UIAxes2,'Componente Impar de Señal Triangular')
                            
                            t1=-(Tiempo):1/Frecuencia:Tiempo;
                            x1= Amplitud*triangularPulse(t1);
                            x_par = (x1+fliplr(x1));
                            plot(app.UIAxes,t1,x_par);
                            title(app.UIAxes,'Componente Par de Señal Triangular')
                    end
                    
                case 'Escalón'
                    switch Clasificacion
                        case 'Por Tiempo'
                            t = -(Tiempo):1/Frecuencia:Tiempo; %Tiempo de Simulación
                            % Generación de las señales
                            x1 = Amplitud*heaviside(t); % Señal escalón
                            plot(app.UIAxes, t, x1);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal de Escalón en Tiempo Continuo');
                            n = -Tiempo:Tiempo; %Tiempo de Simulación
                            % Generación de las señales
                            x1 = Amplitud*heaviside(n); % Señal escalón
                            stem(app.UIAxes2, n, x1);
                            xlabel(app.UIAxes2, 'Muestras');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal de Escalón en Tiempo Discreto');
                        case 'Por Periocidad'
                            % Generar señal de escalón periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud * (mod(t, 2/Frecuencia) < 1/Frecuencia);
                            plot(app.UIAxes, t, x);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal de Escalón Periódica');
                            
                            % Generar señal de escalón no periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud * (t>=0);
                            x = x .* (2*round(rand(size(t)))-1);
                            plot(app.UIAxes2, t, x);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal de Escalón No Periódica');
                        case 'Por Componente'
                            % Generar señal de escalón par
                            t = linspace(-Tiempo, Tiempo, 10000);
                            x = Amplitud * (t>=0);
                            x_par = (1/2)*(x+fliplr(x));
                            plot(app.UIAxes, t, x_par);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Componente par de señal de escalón');
                            
                            t = -(Tiempo):1/Frecuencia:Tiempo; %Tiempo de Simulación
                            % Generación de las señales
                            x1 = Amplitud*heaviside(t); % Señal escalón
                            % Generar señal de escalón impar
                            x_impar = (1/2)*(x1-fliplr(x1));
                            stem(app.UIAxes2, t, x_impar);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Componente impar de señal de escalón');
                    end
                    
                case 'Impulso Triangular'
                    switch Clasificacion
                        case 'Por Tiempo'
                            %Generación de Señal
                            n = 0:5; %Tiempo de Simulación
                            y = Amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)];
                            plot(app.UIAxes,n,y),grid on,xlim([0 5]),ylim([0 1.1])
                            title(app.UIAxes,'Impulso en tiempo continuo')
                            
                            n = 0:5; %Tiempo de Simulación
                            y = Amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)];
                            stem(app.UIAxes2,n,y),grid on,xlim([0 5]),ylim([0 1.1])
                            title('Impulso en tiempo discreto')
                        case 'Por Periocidad'
                            % Generar señal de impulso triangular periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            y = Amplitud * mod(t, 2/Frecuencia) .* (mod(t, 2/Frecuencia) < 1/Frecuencia) + Amplitud * (mod(t, 2/Frecuencia) >= 1/Frecuencia) .* (mod(t, 2/Frecuencia) < 3/Frecuencia);
                            plot(app.UIAxes, t, y);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal de Impulso Triangular Periódica');
                            % Generar señal de impulso triangular no periódica
                            n = 0:5; %Tiempo de Simulación
                            y = Amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)];
                            plot(app.UIAxes2, n, y),grid on,xlim([0 5]),ylim([0 1.1])
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal de Impulso Triangular No Periódica');
                        case 'Por Componente'
                            % Obtener componentes par e impar
                            n = 0:5; %Tiempo de Simulación
                            y = Amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)];
                            y_par = (1/2)*(y+fliplr(y));
                            plot(app.UIAxes, n, y_par),grid on,xlim([0 5]),ylim([0 1.1])
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Componente par de señal de impulso triangular');
                            
                            n1 = 0:5; %Tiempo de Simulación
                            y1 = Amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)];
                            y_impar = (1/2)*(y1-fliplr(y1));
                            plot(app.UIAxes2, n1, y_impar),grid on,xlim([0 5]),ylim([0 1.1])
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Componente impar de señal de impulso triangular');
                            
                    end
                case 'Diente de Sierra'
                    switch Clasificacion
                        case 'Por Tiempo'
                            Fs = 10000; %Frecuencia de Muestreo
                            width=0.5;
                            t = -(Tiempo):1/Fs:Tiempo;
                            y= Amplitud*sawtooth(2*pi*Frecuencia*t,width);
                            plot(app.UIAxes, t, y);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal Diente de Sierra en Tiempo Continuo');
                            
                            % Señal de diente de sierra por tiempo discreto
                            n = -(Tiempo):Tiempo;
                            x = sawtooth(2*pi*Frecuencia/Frecuencia*n, 0.5)*Amplitud;
                            stem(app.UIAxes2, n, x);
                            xlabel(app.UIAxes2, 'Muestras');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal Diente de Sierra en Tiempo Discreto');
                        case 'Por Periocidad'
                            % Señal de diente de sierra periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud * sawtooth(2*pi*Frecuencia*t, 0.5);
                            plot(app.UIAxes, t, x);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal Diente de Sierra Periódica');
                            
                            % Señal de diente de sierra no periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud * sawtooth(2*pi*Frecuencia*t, 0.5);
                            x = x .* (2*round(rand(size(t)))-1);
                            plot(app.UIAxes2, t, x);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal Diente de Sierra No Periódica');
                        case 'Por Componente'
                            % Componente par de señal de diente de sierra
                            t = linspace(-Tiempo, Tiempo, 10000);
                            x = sawtooth(2*pi*Frecuencia*t, 0.5)*Amplitud;
                            x_par = (1/2)*(x+fliplr(x));
                            plot(app.UIAxes, t, x_par);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Componente par de señal Diente de sierra');
                            
                            % Componente impar de señal de diente de sierra
                            x_impar = (1/2)*(x-fliplr(x));
                            stem(app.UIAxes2, t, x_impar);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Componente impar de señal Diente de sierra');
                    end
                    
                case 'Rampa'
                    switch Clasificacion
                        case 'Por Tiempo'
                            t = -(Tiempo):1/Frecuencia:Tiempo;
                            x = Amplitud*(t>=0).*t;
                            plot(app.UIAxes, t, x);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal de Rampa en Tiempo Continuo');
                            n = -Tiempo:Tiempo;
                            x = Amplitud*(n>=0).*n;
                            stem(app.UIAxes2, n, x);
                            xlabel(app.UIAxes2, 'Muestras');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal de Rampa en Tiempo Discreto');
                        case 'Por Periocidad'
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud*(mod(t, 2/Frecuencia)<1/Frecuencia).*t;
                            plot(app.UIAxes, t, x);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal de Rampa Periódica');
                            % Generar señal de rampa no periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud * (t>=0);
                            x = x .* (2*rand(size(t))-1);
                            plot(app.UIAxes2, t, x);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal de Rampa No Periódica');
                        case 'Por Componente'
                            t = linspace(-Tiempo, Tiempo, 10000);
                            x = Amplitud*(t>=0).*t;
                            x_par = (1/2)*(x+fliplr(x));
                            plot(app.UIAxes, t, x_par);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Componente par de señal de Rampa');
                            x_impar = (1/2)*(x-fliplr(x));
                            stem(app.UIAxes2, t, x_impar);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Componente impar de señal de Rampa');
                    end
                    
                case 'Rectangular'
                    switch Clasificacion
                        case 'Por Tiempo'
                            % Señal rectangular por tiempo
                            t = 0:1/Frecuencia:Tiempo-1/Frecuencia;
                            Ancho = 0.2; % Porcentaje del ancho del pulso en relación al periodo
                            y = square(2*pi*50*t, Ancho*100);
                            plot(app.UIAxes, t, y)
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal Rectangular en Tiempo Continuo');
                            
                            % Señal rectangular por tiempo discreto
                            n = -Tiempo:Tiempo;
                            x = Amplitud * (abs(n) <= Tiempo/2);
                            stem(app.UIAxes2, n, x);
                            xlabel(app.UIAxes2, 'Muestras');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal Rectangular en Tiempo Discreto');
                            
                        case 'Por Periocidad'
                            % Señal rectangular periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud * (mod(t, 1/Frecuencia) < Tiempo/2);
                            plot(app.UIAxes, t, x);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal Rectangular Periódica');
                            
                            % Señal rectangular no periódica
                            t = 0:1/(10*Frecuencia):Tiempo;
                            x = Amplitud * (abs(mod(t,Tiempo)-Tiempo/2) < Tiempo/4);
                            x = x .* (2*round(rand(size(t)))-1);
                            plot(app.UIAxes2, t, x);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal Rectangular No Periódica');
                            
                        case 'Por Componente'
                            % Señal rectangular par
                            t = linspace(-Tiempo, Tiempo, 10000);
                            x = Amplitud * (abs(t) <= Tiempo/2);
                            x_par = (1/2)*(x+fliplr(x));
                            plot(app.UIAxes, t, x_par);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Componente par de señal rectangular');
                            
                            % Señal rectangular impar
                            x_impar = (1/2)*(x-fliplr(x));
                            stem(app.UIAxes2, t, x_impar);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Componente impar de señal rectangular');
                    end
                    
                case 'Exponencial Creciente'
                    switch Clasificacion
                        case 'Por Tiempo'
                            t=-(Tiempo):1/Frecuencia:Tiempo;
                            y=exp(t);
                            % Graficar señal
                            plot(app.UIAxes, t, y);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal exponencial creciente en tiempo continuo');
                            % Generar señal exponencial creciente en tiempo discreto
                            n = -Tiempo:Tiempo;
                            yd = Amplitud*exp(n);
                            % Graficar señal
                            stem(app.UIAxes2, n, yd);
                            xlabel(app.UIAxes2, 'n');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal exponencial creciente en tiempo discreto');
                        case 'Por Periocidad'
                            n = -(Tiempo):Tiempo; %Tiempo de Simulación
                            %La señal exponencial creciente periodica
                            title(app.UIAxes,'Señal exponencial creciente periodica')
                            x = Amplitud*exp(n);
                            x_no_periodica = 2*(rand(size(x))+0.5) .* x;
                            % Graficar señal
                            plot(app.UIAxes2, n, x_no_periodica);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal exponencial creciente no periódica');
                            % La señal exponencial creciente no es periódica
                            
                        case 'Por Componente'
                            % La señal exponencial creciente no tiene componente par
                            t=-(Tiempo):1/Frecuencia:Tiempo;
                            y=exp(t);
                            y_par = (1/2)*(y+fliplr(y));
                            plot(app.UIAxes,t,y_par),title(app.UIAxes,'Componente Par de la Señal Exponencial Creciente')
                            y_impar = (y - flip(y))/2;
                            plot(app.UIAxes2,t,y_impar),grid on
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Componente Impar de la Señal Exponencial Creciente')
                    end
                    
                case 'Exponencial Decreciente'
                    switch Clasificacion
                        case 'Por Tiempo'
                            r = 1.5;
                            t = -(Tiempo):1/Frecuencia:Tiempo;
                            y=r.^(-t);
                            plot(app.UIAxes, t, y);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal Exponencial Decreciente en Tiempo Continuo');
                            
                            r=1.5;
                            n = -Tiempo:Tiempo; %Tiempo de Simulación
                            % Generación de la señal
                            y = r.^(-n); % Señal exponencial decreciente
                            stem(app.UIAxes2, n, y);
                            xlabel(app.UIAxes2, 'Muestras');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal Exponencial Decreciente en Tiempo Discreto');
                        case 'Por Periocidad'
                            % Generar señal exponencial decreciente periódica
                            r = 1.5;
                            t = -(Tiempo):1/Frecuencia:Tiempo;
                            y=r.^(-t);
                            plot(app.UIAxes, t, y);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Señal Exponencial Decreciente Periódica');
                            
                            % Generar señal exponencial decreciente no periódica
                            r = 1.5;
                            t = -(Tiempo):1/Frecuencia:Tiempo;
                            y=r.^(-t);
                            y = y .* (2*round(rand(size(t)))-1);
                            plot(app.UIAxes2, t, y);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Señal Exponencial Decreciente No Periódica');
                        case 'Por Componente'
                            r = 1.5;
                            t = -(Tiempo):1/Frecuencia:Tiempo;
                            y=r.^(-t);
                            y_par = (1/2)*(y+fliplr(y));
                            plot(app.UIAxes, t, y_par);
                            xlabel(app.UIAxes, 'Tiempo');
                            ylabel(app.UIAxes, 'Amplitud');
                            title(app.UIAxes, 'Componente par de señal exponencial decreciente');
                            
                            % Generar señal exponencial decreciente impar
                            r = 1.5;
                            t = -(Tiempo):1/Frecuencia:Tiempo;
                            y=r.^(-t);
                            y_impar = (1/2)*(y-fliplr(y));
                            stem(app.UIAxes2, t, y_impar);
                            xlabel(app.UIAxes2, 'Tiempo');
                            ylabel(app.UIAxes2, 'Amplitud');
                            title(app.UIAxes2, 'Componente impar de señal exponencial decreciente');
                    end
            end
            
        end

        % Button pushed function: LimpiarSealButton
        function LimpiarSealButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2); % Limpia los datos de la gráfica
        end

        % Button pushed function: EvaluarSealButton
        function EvaluarSealButtonPushed(app, event)
            % Obtener los valores ingresados por el usuario
            Amplitud = app.AmplitudEditField.Value;
            Frecuencia = app.FrecuenciaEditField.Value;
            Tiempo = app.TiempoEditField.Value;
            
            switch app.ElijaunasealDropDown.Value
                case 'Senoidal'
                    Fs=150;
                    t=0:Tiempo/Fs:Tiempo;
                    y = Amplitud*sin(2*pi*Frecuencia*t);
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = mean(abs(y).^2); % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Cuadrada'
                    
                    Fs = 10000; %Frecuencia de Muestreo
                    duty=50;
                    t = 0:1/Fs:Tiempo-1/Fs; %Tiempo de Simulación
                    y = Amplitud*square(2*pi*Frecuencia*t,duty);
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P =rms(y)^2; % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Exponencial Creciente'
                    
                    t=0:1/Frecuencia:Tiempo;
                    y=exp(t);
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = rms(y)^2; % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Exponencial Decreciente'
                    
                    r = 1.5;
                    t=0:1/Frecuencia:Tiempo;
                    y=r.^(-t);
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = E / Tiempo; % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Triangular'
                    
                    t=-Tiempo:1/Frecuencia:Tiempo;
                    y= Amplitud*triangularPulse(t);
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = mean(abs(y).^2); % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Diente de Sierra'
                    
                    Fs = 10000; %Frecuencia de Muestreo
                    width=0.5;
                    t= 0:1/Fs:Tiempo-1/Fs;
                    y= Amplitud*sawtooth(2*pi*Frecuencia*t,width);
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = mean(abs(y).^2); % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Rampa'
                    
                    t1 = 0:1/Frecuencia:Tiempo;
                    r = t1;
                    y = Amplitud.*[zeros(1,length(t1)),r]; %Generación de Señal
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = mean(abs(y).^2); % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Escalón'
                    
                    t = 0:1/Frecuencia:Tiempo; %Tiempo de Simulación
                    % Generación de las señales
                    y = Amplitud*heaviside(t); % Señal escalón
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = mean(abs(y).^2); % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Impulso Triangular'
                    y = Amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)];
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = mean(abs(y).^2); % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
                    
                case 'Rectangular'
                    
                    t= -Tiempo:1/Frecuencia:Tiempo;
                    y = Amplitud * (abs(t) <= Tiempo/2);
                    % Calcular energía y potencia
                    E = sum(abs(y).^2); % Energía
                    P = mean(abs(y).^2); % Potencia
                    
                    % Determinar tipo de señal
                    if E < Inf && P == 0
                        tipo = 'Señal de Energía';
                    elseif P < Inf && E ~= 0
                        tipo = 'Señal de Potencia';
                    else
                        tipo = 'Ninguna de las dos opciones';
                    end
                    
                    % Mostrar resultado
                    msgbox(tipo,'Tipo')
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Generadordesenal.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {487, 487};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Generadordesenal = uifigure('Visible', 'off');
            app.Generadordesenal.Name = 'Pulse Generator';
            app.Generadordesenal.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Generadordesenal);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'fondo.jpg';
            app.GeneradordesealeselementalesLabel = uilabel(app.LeftPanel);
            app.GeneradordesealeselementalesLabel.Text = 'Generador de señales elementales';
            app.FrecuenciaEditField_2Label = uilabel(app.LeftPanel);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GenerarSealButton = uibutton(app.LeftPanel, 'push');
            app.GenerarSealButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealButtonPushed, true);
            app.GenerarSealButton.Text = 'Generar Señal';
            app.ElijaunasealDropDownLabel = uilabel(app.LeftPanel);
            app.ElijaunasealDropDownLabel.Text = 'Elija una señal';
            app.ElijaunasealDropDown = uidropdown(app.LeftPanel);
            app.ElijaunasealDropDown.Items = {'Cuadrada', 'Exponencial Creciente', 'Exponencial Decreciente', 'Triangular', 'Diente de Sierra', 'Escalón', 'Impulso Triangular', 'Rampa', 'Rectangular', 'Senoidal'};
            app.ElijaunasealDropDown.Value = 'Cuadrada';
            app.OperacinDropDownLabel = uilabel(app.LeftPanel);
            app.OperacinDropDownLabel.Text = 'Operación';
            app.OperacinDropDown = uidropdown(app.LeftPanel);
            app.OperacinDropDown.Items = {'Escalamiento', 'Suma', 'Diferenciacion', 'Multiplicacion', 'Division', 'Relexion', 'Desplazamiento', 'Integracion'};
            app.OperacinDropDown.Value = 'Suma';
            app.LimpiarSealButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarSealButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarSealButtonPushed, true);
            app.LimpiarSealButton.Text = 'Limpiar Señal';
            app.EvaluarSealButton = uibutton(app.LeftPanel, 'push');
            app.EvaluarSealButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluarSealButtonPushed, true);
            app.EvaluarSealButton.Text = 'Evaluar Señal';
            app.TipodeSeal1DropDown_2 = uidropdown(app.LeftPanel);
            app.TipodeSeal1DropDown_2.Items = {'Cuadrada', 'Exponencial Creciente', 'Exponencial Decreciente', 'Triangular', 'Diente de Sierra', 'Escalón', 'Impulso Triangular', 'Rampa', 'Rectangular', 'Senoidal'};
            app.TipodeSeal1DropDown_2.Value = 'Cuadrada';
            app.TipodeSeal1DropDownLabel_2 = uilabel(app.LeftPanel);
            app.TipodeSeal1DropDownLabel_2.Text = 'Elija una señal';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'water-drops-gaa15cd8f0_640.jpg';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.Generadordesenal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Generadordesenal)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Generadordesenal)
        end
    end
end